classdef DaysimeterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                % Daysimete...
        AppNameLabel            matlab.ui.control.Label         % Daysimete...
        TabGroup                matlab.ui.container.TabGroup    % Home, Pre...
        HomeTab                 matlab.ui.container.Tab         % Home
        LabelListBox            matlab.ui.control.Label         % Select Da...
        DaysimeterList          matlab.ui.control.ListBox       % proto 1, ...
        SelectAllButton         matlab.ui.control.Button        % Select All
        RefreshButton           matlab.ui.control.Button        % Refresh
        QuickStartPanel         matlab.ui.container.Panel       % Quick Sta...
        Start90Button           matlab.ui.control.Button        % 90 sec
        Start30Button           matlab.ui.control.Button        % 30 sec
        Start60Button           matlab.ui.control.Button        % 60 sec
        Start120Button          matlab.ui.control.Button        % 120 sec
        Start180Button          matlab.ui.control.Button        % 180 sec
        StopButton              matlab.ui.control.Button        % Stop
        DownloadButton          matlab.ui.control.Button        % Download
        DurationPanel           matlab.ui.container.Panel       % Will Reco...
        Label                   matlab.ui.control.Label         % 5.5 days
        Label2                  matlab.ui.control.Label         % 11 days
        Label3                  matlab.ui.control.Label         % 16.5 days
        Label4                  matlab.ui.control.Label         % 22 days
        Label5                  matlab.ui.control.Label         % 33 days
        PreferencesTab          matlab.ui.container.Tab         % Preferences
        DownloadDirPanel        matlab.ui.container.Panel       % Download ...
        OpenDownloadDirButton   matlab.ui.control.Button        % Open
        SetDownloadButton       matlab.ui.control.Button        % Set
        DownloadDirLabel        matlab.ui.control.Label         % DownloadD...
        AskOnDownloadCheckBox   matlab.ui.control.CheckBox      % Ask to se...
        StartLogDirPanel        matlab.ui.container.Panel       % Start Log...
        OpenStartLogDirButton   matlab.ui.control.Button        % Open
        SetStartLogDirButton    matlab.ui.control.Button        % Set
        StartLogDirLabel        matlab.ui.control.Label         % StartLogD...
        TimeZoneButtonGroup     matlab.ui.container.ButtonGroup % Time Zone
        AutoTimeZoneRadioButton matlab.ui.control.RadioButton   % local
        ManualTimeZoneRadioButton matlab.ui.control.RadioButton % manual
        UtcSpinnerLabel         matlab.ui.control.Label         % UTC
        UtcSpinner              matlab.ui.control.Spinner       % [-12 12]
        DownloadOptionsPanel    matlab.ui.container.Panel       % Download ...
        CDFCheckBox             matlab.ui.control.CheckBox      % Save CDF
        CSVCheckBox             matlab.ui.control.CheckBox      % Save CSV
        AboutTab                matlab.ui.container.Tab         % About
        CopyrightLabel          matlab.ui.control.Label         % Copyright...
    end

    
    properties (Access = private)
        SelectedDaysimeterPaths % Cell array of file paths to selected Daysimeters
        Preferences             % Struct that contains App preferences
    end
    
    methods (Access = private)
        
        % Refresh the list of conncected Daysimeters
        function results = refreshDaysimeterList(app)
            daysimeterPaths = io.getDaysimeters;
            deviceSns = cellfun(@io.getDeviceSn,daysimeterPaths,'UniformOutput',false);
            app.DaysimeterList.Items = deviceSns';
            app.DaysimeterList.ItemsData = daysimeterPaths';
            
            if isempty(daysimeterPaths)
                app.DaysimeterList.Items = {'None'; 'detected'};
                app.enableButtons(app.HomeTab,'off');
            else
                app.DaysimeterList.Value = app.DaysimeterList.ItemsData; % Select all Daysimeters
                app.SelectedDaysimeterPaths = app.DaysimeterList.ItemsData;
                app.enableButtons(app.HomeTab,'on');
            end
        end % End of refreshDaysimeterList
        
        % Load App Preferences from file or initialize them
        function results = loadPreferences(app)
            
            app.Preferences = struct;
            app.Preferences.DownloadDir = getpref('DaysimeterApp','DownloadDir','');
            app.Preferences.AskOnDownload = getpref('DaysimeterApp','AskOnDownload',true);
            app.Preferences.StartLogDir = getpref('DaysimeterApp','StartLogDir','');
            app.Preferences.TimeZoneMode = getpref('DaysimeterApp','TimeZoneMode','local');
            app.Preferences.UtcOffset = getpref('DaysimeterApp','UtcOffset',app.getLocalUtcOffset);
            
            app.setDownloadDir;
            app.setAskOnDownload;
            app.setStartLogDir;
            app.setTimeZone;
        end % End of loadPreferences
        
        % Save App preferences to file
        function results = savePreferences(app)
            names = fieldnames(app.Preferences);
            for iPref = 1:numel(names)
                setpref('DaysimeterApp',names{iPref},app.Preferences.(names{iPref}));
            end
        end
        
        function utcOffset = getLocalUtcOffset(app)
            t = java.util.Date();
            utcOffset = t.getTimezoneOffset()/60;
        end % End of savePreferences
        
        function results = setDownloadDir(app)
            app.DownloadDirLabel.Text = app.Preferences.DownloadDir;
            if isempty(app.Preferences.DownloadDir)
                app.OpenDownloadDirButton.Enable = 'off';
            else
                app.OpenDownloadDirButton.Enable = 'on';
            end
            app.savePreferences;
        end % End of setDownloadDir
        
        function results = setStartLogDir(app)
            app.StartLogDirLabel.Text = app.Preferences.StartLogDir;
            if isempty(app.Preferences.StartLogDir)
                app.OpenStartLogDirButton.Enable = 'off';
            else
                app.OpenStartLogDirButton.Enable = 'on';
            end
            app.savePreferences;
        end % End of setStartLogDir
        
        function results = setTimeZone(app)
            switch app.Preferences.TimeZoneMode
                case 'local'
                    app.TimeZoneButtonGroup.SelectedObject = app.TimeZoneButtonGroup.Buttons(1);
                    app.UtcSpinner.Enable = 'off';
                    % Set the selected offset to the local time zone
                    app.Preferences.UtcOffset = app.getLocalUtcOffset;
                case 'manual'
                    app.TimeZoneButtonGroup.SelectedObject = app.TimeZoneButtonGroup.Buttons(2);
                    app.UtcSpinner.Enable = 'on';
                otherwise
                    app.Preferences.TimeZoneMode = 'local';
                    app.TimeZoneButtonGroup.SelectedObject = app.TimeZoneButtonGroup.Buttons(1);
                    app.UtcSpinner.Enable = 'off';
                    % Set the selected offset to the local time zone
                    app.Preferences.UtcOffset = app.getLocalUtcOffset;
            end
            app.UtcSpinner.Value = app.Preferences.UtcOffset;
        end % End of setTimeZone
        
        function results = startDaysimeters(app,loggingInterval)
            h = app.wait;
            
            if strcmp(app.Preferences.TimeZoneMode,'manual')
                timeAdjustment = (app.Preferences.UtcOffset - app.getLocalUtcOffset)/24;
            else
                timeAdjustment = 0;
            end
            parameters = struct;
            parameters.status = io.daysimeterStatus.Start;
            parameters.startDateTime = now + timeAdjustment;
            parameters.loggingInterval = loggingInterval;
            
            log_infoPaths = fullfile(app.SelectedDaysimeterPaths,'log_info.txt');
            
            if iscell(log_infoPaths)
                f = @(log_infoPath) io.setLogInfo(log_infoPath,parameters);
                results = cellfun(f, log_infoPaths,'UniformOutput',false);
            else
                results = io.setLogInfo(log_infoPaths,parameters);
            end
            
            delete(h);
            h = msgbox({'Daysimeters are initialized. They will start when disconnected.';...
                ['Start date-time: ',datestr(parameters.startDateTime)];...
                ['Logging interval: ',num2str(parameters.loggingInterval)]},'Started');
        end
        
        function results = stopDaysimeters(app)
            h = app.wait;
            
            parameters = struct;
            parameters.status = io.daysimeterStatus.Standby;
            
            log_infoPaths = fullfile(app.SelectedDaysimeterPaths,'log_info.txt');
            
            if iscell(log_infoPaths)
                f = @(log_infoPath) io.setLogInfo(log_infoPath,parameters);
                results = cellfun(f, log_infoPaths,'UniformOutput',false);
            else
                results = io.setLogInfo(log_infoPaths,parameters);
            end
            
            delete(h);
            h = msgbox('Daysimeters are stopped.','Stopped');
        end
        
        function results = enableButtons(app,parent,value)
            h = findobj(parent,'-property','ButtonPushedFcn','-not','Text','Refresh'); % Find buttons belonging to parent obj
            set(h,'Enable',value); % Assign new value for enabled property ('on' or 'off')
        end
        
        function results = setAskOnDownload(app)
            app.AskOnDownloadCheckBox.Value = app.Preferences.AskOnDownload;
        end
        
        function results = pullFocus(app)
            % Force MATLAB to return focus to UI window
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end
        
        function results = downloadDaysimeters(app)
            h = app.wait;
            
            daysimeterPaths = app.SelectedDaysimeterPaths;
            savePath = app.Preferences.DownloadDir;
            makeCsvFlag = false;
            makeCdfFlag = false;
            if iscell(daysimeterPaths)
                f = @(c) io.downloadDaysimeter(c,savePath,makeCsvFlag,makeCdfFlag);
                results = cellfun(f,daysimeterPaths,'UniformOutput',false);
            else
                io.downloadDaysimeter(daysimeterPaths,savePath,makeCsvFlag,makeCdfFlag);
            end
            
            delete(h);
            h = msgbox('Download complete.','Success');
        end

        function h = wait(app)
            h = msgbox('Please wait.');
            hButton = findobj(h,'Type','UIControl');
            hButton.Enable = 'off';
            drawnow;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.loadPreferences; % Load the App preferences
            app.refreshDaysimeterList; % Refresh the Daysimeter list
        end

        % RefreshButton button pushed function
        function RefreshButtonPushed(app)
            app.refreshDaysimeterList; % Refresh the Daysimeter list
        end

        % SelectAllButton button pushed function
        function SelectAllButtonPushed(app)
            app.DaysimeterList.Value = app.DaysimeterList.ItemsData;
        end

        % DaysimeterList value changed function
        function DaysimeterListValueChanged(app)
            value = app.DaysimeterList.Value;
            app.SelectedDaysimeterPaths = value;
        end

        % SetDownloadButton button pushed function
        function SetDownloadButtonPushed(app)
            temp = uigetdir(app.Preferences.DownloadDir,'Please select a location to save downloaded files.');
            if temp ~= 0 % If uigetdir was cancelled
                app.Preferences.DownloadDir = temp;
            end
            app.pullFocus; % Return focus to App window
            app.setDownloadDir;
        end

        % SetStartLogDirButton button pushed function
        function SetStartLogDirButtonPushed(app)
            temp = uigetdir(app.Preferences.StartLogDir,'Please select a location to save start logs.');
            
            if temp ~= 0 % If uigetdir was cancelled
                app.Preferences.StartLogDir = temp;
            end
            app.pullFocus; % Return focus to App window
            app.setStartLogDir;
        end

        % OpenDownloadDirButton button pushed function
        function OpenDownloadDirButtonPushed(app)
            if ~isempty(app.Preferences.DownloadDir)
                finder(app.Preferences.DownloadDir);
            end
        end

        % OpenStartLogDirButton button pushed function
        function OpenStartLogDirButtonPushed(app)
            if ~isempty(app.Preferences.StartLogDir)
                finder(app.Preferences.StartLogDir);
            end
        end

        % TimeZoneButtonGroup selection change function
        function TimeZoneButtonGroupSelectionChanged(app, event)
            selectedButton = app.TimeZoneButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'manual')
                % enable the spinner if set to manual
                app.UtcSpinner.Enable = 'on';
                app.Preferences.TimeZoneMode = 'manual';
            else
                % disable the spinner if set to local
                app.UtcSpinner.Enable = 'off';
                app.Preferences.TimeZoneMode = 'local';
            end
            app.setTimeZone;
        end

        % UtcSpinner value changed function
        function UtcSpinnerValueChanged(app)
            value = app.UtcSpinner.Value;
            app.Preferences.UtcOffset = value;
            app.savePreferences;
        end

        % DownloadButton button pushed function
        function DownloadButtonPushed(app)
            app.enableButtons(app.HomeTab,'off');
            % Ask user to select new save location if preferred
            if app.Preferences.AskOnDownload || isempty(app.Preferences.DownloadDir) || exist(app.Preferences.DownloadDir,'dir') ~= 7
                temp = uigetdir(app.Preferences.DownloadDir,'Please select a location to save downloaded files.');
                if temp ~= 0 % If uigetdir was cancelled
                    app.Preferences.DownloadDir = temp;
                end
                app.pullFocus; % Return focus to App window
                app.setDownloadDir;
            end
            app.downloadDaysimeters;
            app.enableButtons(app.HomeTab,'on');
        end

        % Start30Button button pushed function
        function Start30ButtonPushed(app)
            app.enableButtons(app.HomeTab,'off');
            app.startDaysimeters(30);
            app.enableButtons(app.HomeTab,'on');
        end

        % Start60Button button pushed function
        function Start60ButtonPushed(app)
            app.enableButtons(app.HomeTab,'off');
            app.startDaysimeters(60);
            app.enableButtons(app.HomeTab,'on');
        end

        % Start90Button button pushed function
        function Start90ButtonPushed(app)
            app.enableButtons(app.HomeTab,'off');
            app.startDaysimeters(90);
            app.enableButtons(app.HomeTab,'on');
        end

        % Start120Button button pushed function
        function Start120ButtonPushed(app)
            app.enableButtons(app.HomeTab,'off');
            app.startDaysimeters(120);
            app.enableButtons(app.HomeTab,'on');
        end

        % Start180Button button pushed function
        function Start180ButtonPushed(app)
            app.enableButtons(app.HomeTab,'off');
            app.startDaysimeters(180);
            app.enableButtons(app.HomeTab,'on');
        end

        % StopButton button pushed function
        function StopButtonPushed(app)
            app.enableButtons(app.HomeTab,'off');
            app.stopDaysimeters;
            app.enableButtons(app.HomeTab,'on');
        end

        % AskOnDownloadCheckBox value changed function
        function AskOnDownloadCheckBoxValueChanged(app)
            app.Preferences.AskOnDownload = app.AskOnDownloadCheckBox.Value;
            app.setAskOnDownload;
            app.savePreferences;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Daysimeter App';
            setAutoResize(app, app.UIFigure, true)
            app.AppNameLabel = uilabel(app.UIFigure);
            app.AppNameLabel.Text = 'Daysimeter App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Units = 'pixels';
            app.HomeTab.Title = 'Home';
            app.LabelListBox = uilabel(app.HomeTab);
            app.LabelListBox.Text = 'Select Daysimeters';
            app.DaysimeterList = uilistbox(app.HomeTab);
            app.DaysimeterList.Items = {'proto 1', 'proto 2'};
            app.DaysimeterList.Multiselect = 'on';
            app.DaysimeterList.ValueChangedFcn = createCallbackFcn(app, @DaysimeterListValueChanged);
            app.DaysimeterList.Value = {'proto 1'};
            app.SelectAllButton = uibutton(app.HomeTab, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed);
            app.SelectAllButton.Text = 'Select All';
            app.RefreshButton = uibutton(app.HomeTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed);
            app.RefreshButton.Text = 'Refresh';
            app.QuickStartPanel = uipanel(app.HomeTab);
            app.QuickStartPanel.Title = 'Quick Start with Logging Rate of:';
            app.QuickStartPanel.Units = 'pixels';
            app.Start90Button = uibutton(app.QuickStartPanel, 'push');
            app.Start90Button.ButtonPushedFcn = createCallbackFcn(app, @Start90ButtonPushed);
            app.Start90Button.Text = '90 sec';
            app.Start30Button = uibutton(app.QuickStartPanel, 'push');
            app.Start30Button.ButtonPushedFcn = createCallbackFcn(app, @Start30ButtonPushed);
            app.Start30Button.Text = '30 sec';
            app.Start60Button = uibutton(app.QuickStartPanel, 'push');
            app.Start60Button.ButtonPushedFcn = createCallbackFcn(app, @Start60ButtonPushed);
            app.Start60Button.Text = '60 sec';
            app.Start120Button = uibutton(app.QuickStartPanel, 'push');
            app.Start120Button.ButtonPushedFcn = createCallbackFcn(app, @Start120ButtonPushed);
            app.Start120Button.Text = '120 sec';
            app.Start180Button = uibutton(app.QuickStartPanel, 'push');
            app.Start180Button.ButtonPushedFcn = createCallbackFcn(app, @Start180ButtonPushed);
            app.Start180Button.Text = '180 sec';
            app.StopButton = uibutton(app.HomeTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed);
            app.StopButton.Icon = 'stop_24.png';
            app.StopButton.Text = 'Stop';
            app.DownloadButton = uibutton(app.HomeTab, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed);
            app.DownloadButton.Icon = 'download_24.png';
            app.DownloadButton.Text = 'Download';
            app.DurationPanel = uipanel(app.HomeTab);
            app.DurationPanel.Title = 'Will Record for:';
            app.DurationPanel.Units = 'pixels';
            app.Label = uilabel(app.DurationPanel);
            app.Label.Text = '5.5 days';
            app.Label2 = uilabel(app.DurationPanel);
            app.Label2.Text = '11 days';
            app.Label3 = uilabel(app.DurationPanel);
            app.Label3.Text = '16.5 days';
            app.Label4 = uilabel(app.DurationPanel);
            app.Label4.Text = '22 days';
            app.Label5 = uilabel(app.DurationPanel);
            app.Label5.Text = '33 days';
            app.PreferencesTab = uitab(app.TabGroup);
            app.PreferencesTab.Units = 'pixels';
            app.PreferencesTab.Title = 'Preferences';
            app.DownloadDirPanel = uipanel(app.PreferencesTab);
            app.DownloadDirPanel.Title = 'Download Directory: data is saved here on download';
            app.DownloadDirPanel.Units = 'pixels';
            app.OpenDownloadDirButton = uibutton(app.DownloadDirPanel, 'push');
            app.OpenDownloadDirButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDownloadDirButtonPushed);
            app.OpenDownloadDirButton.Text = 'Open';
            app.SetDownloadButton = uibutton(app.DownloadDirPanel, 'push');
            app.SetDownloadButton.ButtonPushedFcn = createCallbackFcn(app, @SetDownloadButtonPushed);
            app.SetDownloadButton.Text = 'Set';
            app.DownloadDirLabel = uilabel(app.DownloadDirPanel);
            app.DownloadDirLabel.Text = 'DownloadDirLabel';
            app.AskOnDownloadCheckBox = uicheckbox(app.DownloadDirPanel);
            app.AskOnDownloadCheckBox.ValueChangedFcn = createCallbackFcn(app, @AskOnDownloadCheckBoxValueChanged);
            app.AskOnDownloadCheckBox.Text = 'Ask to select a new save location on each download session';
            app.AskOnDownloadCheckBox.Value = true;
            app.StartLogDirPanel = uipanel(app.PreferencesTab);
            app.StartLogDirPanel.Title = 'Start Log Directory: copies of log files are saved here on start';
            app.StartLogDirPanel.Units = 'pixels';
            app.OpenStartLogDirButton = uibutton(app.StartLogDirPanel, 'push');
            app.OpenStartLogDirButton.ButtonPushedFcn = createCallbackFcn(app, @OpenStartLogDirButtonPushed);
            app.OpenStartLogDirButton.Text = 'Open';
            app.SetStartLogDirButton = uibutton(app.StartLogDirPanel, 'push');
            app.SetStartLogDirButton.ButtonPushedFcn = createCallbackFcn(app, @SetStartLogDirButtonPushed);
            app.SetStartLogDirButton.Text = 'Set';
            app.StartLogDirLabel = uilabel(app.StartLogDirPanel);
            app.StartLogDirLabel.Text = 'StartLogDirLabel';
            app.TimeZoneButtonGroup = uibuttongroup(app.PreferencesTab);
            app.TimeZoneButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TimeZoneButtonGroupSelectionChanged, true);
            app.TimeZoneButtonGroup.Title = 'Time Zone';
            app.TimeZoneButtonGroup.Units = 'pixels';
            app.AutoTimeZoneRadioButton = uiradiobutton(app.TimeZoneButtonGroup);
            app.AutoTimeZoneRadioButton.Text = 'local';
            app.AutoTimeZoneRadioButton.Value = true;
            app.ManualTimeZoneRadioButton = uiradiobutton(app.TimeZoneButtonGroup);
            app.ManualTimeZoneRadioButton.Text = 'manual';
            app.UtcSpinnerLabel = uilabel(app.TimeZoneButtonGroup);
            app.UtcSpinnerLabel.Text = 'UTC';
            app.UtcSpinner = uispinner(app.TimeZoneButtonGroup);
            app.UtcSpinner.ValueChangedFcn = createCallbackFcn(app, @UtcSpinnerValueChanged);
            app.UtcSpinner.Limits = [-12 12];
            app.UtcSpinner.RoundFractionalValues = 'on';
            app.UtcSpinner.ValueDisplayFormat = '%+.0f';
            app.UtcSpinner.Enable = 'off';
            app.DownloadOptionsPanel = uipanel(app.PreferencesTab);
            app.DownloadOptionsPanel.Title = 'Download Options (coming soon)';
            app.DownloadOptionsPanel.Units = 'pixels';
            app.CDFCheckBox = uicheckbox(app.DownloadOptionsPanel);
            app.CDFCheckBox.Enable = 'off';
            app.CDFCheckBox.Text = 'Save CDF';
            app.CSVCheckBox = uicheckbox(app.DownloadOptionsPanel);
            app.CSVCheckBox.Enable = 'off';
            app.CSVCheckBox.Text = 'Save CSV';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Units = 'pixels';
            app.AboutTab.Title = 'About';
            app.CopyrightLabel = uilabel(app.AboutTab);
            app.CopyrightLabel.Text = 'Copyright 2016 Rensselaer Polytechnic Institute, Troy, NY 12180 USA - All rights reserved';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DaysimeterApp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end